classdef robot_control_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Jointangle2EditField       matlab.ui.control.EditField
        Jointangle2EditFieldLabel  matlab.ui.control.Label
        Jointangle3EditField       matlab.ui.control.EditField
        Jointangle3EditFieldLabel  matlab.ui.control.Label
        Jointangle1EditField       matlab.ui.control.EditField
        Jointangle1EditFieldLabel  matlab.ui.control.Label
        InversekinematicsLabel     matlab.ui.control.Label
        DirectkinematicsLabel      matlab.ui.control.Label
        RobotcontrolcenterLabel    matlab.ui.control.Label
        Joint2angleSlider          matlab.ui.control.Slider
        Joint2angleSliderLabel     matlab.ui.control.Label
        Joint3angleSlider          matlab.ui.control.Slider
        Joint3angleSliderLabel     matlab.ui.control.Label
        PosZEditField_inverse      matlab.ui.control.NumericEditField
        PosZEditField_2Label       matlab.ui.control.Label
        PosYEditField_direct       matlab.ui.control.EditField
        PosYEditField_2Label       matlab.ui.control.Label
        PosZEditField_direct       matlab.ui.control.EditField
        PosZEditFieldLabel         matlab.ui.control.Label
        PosXEditField_direct       matlab.ui.control.EditField
        PosXEditField_2Label       matlab.ui.control.Label
        CalculatepositionButton    matlab.ui.control.Button
        Joint3angleEditField       matlab.ui.control.NumericEditField
        Joint3angleEditFieldLabel  matlab.ui.control.Label
        Joint2angleEditField       matlab.ui.control.NumericEditField
        Joint2angleEditFieldLabel  matlab.ui.control.Label
        Joint1angleEditField       matlab.ui.control.NumericEditField
        Joint1angleEditFieldLabel  matlab.ui.control.Label
        CalculateanglesButton      matlab.ui.control.Button
        PosYEditField_inverse      matlab.ui.control.NumericEditField
        PosYEditFieldLabel         matlab.ui.control.Label
        PosXEditField_inverse      matlab.ui.control.NumericEditField
        PosXEditFieldLabel         matlab.ui.control.Label
        NacinradaButtonGroup       matlab.ui.container.ButtonGroup
        InverznoButton             matlab.ui.control.RadioButton
        DirektnoButton             matlab.ui.control.RadioButton
        Joint1angleSlider          matlab.ui.control.Slider
        Joint1angleSlider_2Label   matlab.ui.control.Label
    end

    
    properties (Access = private)
        SimulinkConstantBlockName % Description
        SimulinkConstantBlockName1
        SimulinkConstantBlockName2
        SimulinkConstantBlockName3
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SimulinkConstantBlockName1 = "simulation_three_motors/Constant1";
            app.SimulinkConstantBlockName2 = "simulation_three_motors/Constant2";
            app.SimulinkConstantBlockName3 = "simulation_three_motors/Constant3";
            app.NacinradaButtonGroup.SelectedObject = app.DirektnoButton;
            app.NacinradaButtonGroupSelectionChanged();
        end

        % Value changed function: Joint1angleSlider
        function Joint1angleSliderValueChanged(app, event)
            angle_1 = app.Joint1angleSlider.Value;
            %set_param(app.SimulinkConstantBlockName1, 'Value', mat2str(angle_1));
            app.Joint1angleEditField.Value = angle_1;
        end

        % Callback function
        function MotorspeedEditFieldValueChanged(app, event)
            new_velocity_string = app.MotorspeedEditField.Value;
            
            set_param(app.SimulinkConstantBlockName, 'Value', new_velocity_string);
                app.Joint1angleSlider.Value = str2double(new_velocity_string);
        end

        % Selection changed function: NacinradaButtonGroup
        function NacinradaButtonGroupSelectionChanged(app, event)
            selectedButton = app.NacinradaButtonGroup.SelectedObject;
            
            if selectedButton == app.DirektnoButton
                app.PosXEditField_inverse.Enable = false;
                app.PosYEditField_inverse.Enable = false;
                app.PosZEditField_inverse.Enable = false;
                app.CalculateanglesButton.Enable = false;
                app.Jointangle1EditField.Enable = false;
                app.Jointangle2EditField.Enable = false;
                app.Jointangle3EditField.Enable = false;
                app.Joint1angleEditField.Enable = true;
                app.Joint2angleEditField.Enable = true;
                app.Joint3angleEditField.Enable = true;
                app.PosXEditField_direct.Enable = true;
                app.PosYEditField_direct.Enable = true;
                app.PosZEditField_direct.Enable = true;
                app.Joint1angleSlider.Enable = true;
                app.Joint2angleSlider.Enable = true;    
                app.Joint3angleSlider.Enable = true;
                app.CalculatepositionButton.Enable = true;

            elseif selectedButton == app.InverznoButton
                app.PosXEditField_inverse.Enable = true;
                app.PosYEditField_inverse.Enable = true;
                app.PosZEditField_inverse.Enable = true;
                app.CalculateanglesButton.Enable = true;
                app.Jointangle1EditField.Enable = true;
                app.Jointangle2EditField.Enable = true;
                app.Jointangle3EditField.Enable = true;
                app.Joint1angleEditField.Enable = false;
                app.Joint2angleEditField.Enable = false;
                app.Joint3angleEditField.Enable = false;
                app.PosXEditField_direct.Enable = false;
                app.PosYEditField_direct.Enable = false;
                app.PosZEditField_direct.Enable = false;
                app.Joint1angleSlider.Enable = false;
                app.Joint2angleSlider.Enable = false;    
                app.Joint3angleSlider.Enable = false;
                app.CalculatepositionButton.Enable = false;

            else 
                %%
            end
            
                
        end

        % Value changed function: PosYEditField_inverse
        function PosYEditField_inverseValueChanged(app, event)
            valueY = app.PosYEditField_inverse.Value;
            
        end

        % Value changed function: PosXEditField_inverse
        function PosXEditField_inverseValueChanged(app, event)
            valueX = app.PosXEditField_inverse.Value;
            
        end

        % Button pushed function: CalculateanglesButton
        function CalculateanglesButtonPushed(app, event)
            PosX = app.PosXEditField_inverse.Value;
            PosY = app.PosYEditField_inverse.Value;
            PosZ = app.PosZEditField_inverse.Value;

            angles = inverse_kinematics(PosX, PosY, PosZ);
            joint_1_angle = angles(1);
            joint_2_angle = angles(2);
            joint_3_angle = angles(3);

            disp(angles);

            % delta_angle = 5;

            % joint_1_angle_f = joint_1_angle
            % joint_2_angle_f = joint_2_angle - delta_angle;
            % joint_3_angle_f = joint_3_angle - delta_angle;

            joint_1_angle_f = joint_1_angle;
            joint_2_angle_f = joint_2_angle;
            joint_3_angle_f = joint_3_angle;

            joint_1_angle_f = 180 - joint_1_angle_f;
            joint_2_angle_f = 180 + joint_2_angle_f;
            joint_3_angle_f = 180 - joint_3_angle_f;

            
            set_param(app.SimulinkConstantBlockName1, 'Value', mat2str(joint_1_angle_f));
            set_param(app.SimulinkConstantBlockName2, 'Value', mat2str(joint_2_angle_f));   
            set_param(app.SimulinkConstantBlockName3, 'Value', mat2str(joint_3_angle_f));
            app.Jointangle1EditField.Value = num2str(joint_1_angle);
            app.Jointangle2EditField.Value = num2str(joint_2_angle);
            app.Jointangle3EditField.Value = num2str(joint_3_angle);

    %         if 0 < PosX + PosY && PosX + PosY < 100 && PosX > 0 && PosY > 0
    %             % Do nothing, the values are valid
    %         else
    %     % Alert the user
    %            uialert(app.UIFigure,'Invalid values.','Error', ...
    %                'Icon', 'error');
    % 
    % end
            
        end

        % Value changed function: Joint1angleEditField
        function Joint1angleEditFieldValueChanged(app, event)
            angle_1 = app.Joint1angleEditField.Value;
            %set_param(app.SimulinkConstantBlockName1, 'Value', mat2str(angle_1));
            app.Joint1angleSlider.Value = angle_1;
        end

        % Value changed function: Joint2angleEditField
        function Joint2angleEditFieldValueChanged(app, event)
            angle_2 = app.Joint2angleEditField.Value;
            %set_param(app.SimulinkConstantBlockName2, 'Value', mat2str(angle_2));
            app.Joint2angleSlider.Value = angle_2;
        end

        % Value changed function: Joint3angleEditField
        function Joint3angleEditFieldValueChanged(app, event)
            angle_3 = app.Joint3angleEditField.Value;
            %et_param(app.SimulinkConstantBlockName3, 'Value', mat2str(angle_3));
            app.Joint3angleSlider.Value = angle_3;
        end

        % Value changed function: Joint2angleSlider
        function Joint2angleSliderValueChanged(app, event)
            angle_2 = app.Joint2angleSlider.Value;
            %set_param(app.SimulinkConstantBlockName2, 'Value', mat2str(angle_2));
            app.Joint2angleEditField.Value = angle_2;
        end

        % Value changed function: Joint3angleSlider
        function Joint3angleSliderValueChanged(app, event)
            angle_3 = app.Joint3angleSlider.Value;
            %set_param(app.SimulinkConstantBlockName3, 'Value', mat2str(angle_3));
            app.Joint3angleEditField.Value = angle_3;
        end

        % Button pushed function: CalculatepositionButton
        function CalculatepositionButtonPushed(app, event)
            joint_1_angle = app.Joint1angleEditField.Value;
            joint_2_angle = app.Joint2angleEditField.Value;
            joint_3_angle = app.Joint3angleEditField.Value;
           
            % delta_angle = 5;

            % joint_1_angle_f = joint_1_angle;
            % joint_2_angle_f = joint_2_angle - delta_angle;
            % joint_3_angle_f = joint_3_angle - delta_angle;

            joint_1_angle_f = joint_1_angle;
            joint_2_angle_f = joint_2_angle;
            joint_3_angle_f = joint_3_angle;

            joint_1_angle_f = 180 - joint_1_angle;
            joint_2_angle_f = 180 + joint_2_angle_f;
            joint_3_angle_f = 180 - joint_3_angle_f;

            set_param(app.SimulinkConstantBlockName1, 'Value', mat2str(joint_1_angle_f));
            set_param(app.SimulinkConstantBlockName2, 'Value', mat2str(joint_2_angle_f));
            set_param(app.SimulinkConstantBlockName3, 'Value', mat2str(joint_3_angle_f));
            location = direct_kinematics(joint_1_angle, joint_2_angle, joint_3_angle);
            app.PosXEditField_direct.Value = num2str(location(1));
            app.PosYEditField_direct.Value = num2str(location(2));
            app.PosZEditField_direct.Value = num2str(location(3));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Joint1angleSlider_2Label = uilabel(app.UIFigure);
            app.Joint1angleSlider_2Label.Text = 'Joint 1 angle';
            app.Joint1angleSlider = uislider(app.UIFigure);
            app.Joint1angleSlider.Limits = [-90 90];
            app.Joint1angleSlider.ValueChangedFcn = createCallbackFcn(app, @Joint1angleSliderValueChanged, true);
            app.NacinradaButtonGroup = uibuttongroup(app.UIFigure);
            app.NacinradaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NacinradaButtonGroupSelectionChanged, true);
            app.NacinradaButtonGroup.Title = 'Nacin rada';
            app.DirektnoButton = uiradiobutton(app.NacinradaButtonGroup);
            app.DirektnoButton.Text = 'Direktno';
            app.DirektnoButton.Value = true;
            app.InverznoButton = uiradiobutton(app.NacinradaButtonGroup);
            app.InverznoButton.Text = 'Inverzno';
            app.PosXEditFieldLabel = uilabel(app.UIFigure);
            app.PosXEditFieldLabel.Text = 'Pos X';
            app.PosXEditField_inverse = uieditfield(app.UIFigure, 'numeric');
            app.PosXEditField_inverse.ValueChangedFcn = createCallbackFcn(app, @PosXEditField_inverseValueChanged, true);
            app.PosYEditFieldLabel = uilabel(app.UIFigure);
            app.PosYEditFieldLabel.Text = 'Pos Y';
            app.PosYEditField_inverse = uieditfield(app.UIFigure, 'numeric');
            app.PosYEditField_inverse.ValueChangedFcn = createCallbackFcn(app, @PosYEditField_inverseValueChanged, true);
            app.CalculateanglesButton = uibutton(app.UIFigure, 'push');
            app.CalculateanglesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateanglesButtonPushed, true);
            app.CalculateanglesButton.Text = 'Calculate angles ';
            app.Joint1angleEditFieldLabel = uilabel(app.UIFigure);
            app.Joint1angleEditFieldLabel.Text = 'Joint 1 angle ';
            app.Joint1angleEditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint1angleEditField.Limits = [-90 90];
            app.Joint1angleEditField.ValueChangedFcn = createCallbackFcn(app, @Joint1angleEditFieldValueChanged, true);
            app.Joint2angleEditFieldLabel = uilabel(app.UIFigure);
            app.Joint2angleEditFieldLabel.Text = 'Joint 2 angle ';
            app.Joint2angleEditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint2angleEditField.Limits = [0 90];
            app.Joint2angleEditField.ValueChangedFcn = createCallbackFcn(app, @Joint2angleEditFieldValueChanged, true);
            app.Joint3angleEditFieldLabel = uilabel(app.UIFigure);
            app.Joint3angleEditFieldLabel.Text = 'Joint 3 angle ';
            app.Joint3angleEditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint3angleEditField.Limits = [0 90];
            app.Joint3angleEditField.ValueChangedFcn = createCallbackFcn(app, @Joint3angleEditFieldValueChanged, true);
            app.CalculatepositionButton = uibutton(app.UIFigure, 'push');
            app.CalculatepositionButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatepositionButtonPushed, true);
            app.CalculatepositionButton.Text = 'Calculate position';
            app.PosXEditField_2Label = uilabel(app.UIFigure);
            app.PosXEditField_2Label.Text = 'Pos X';
            app.PosXEditField_direct = uieditfield(app.UIFigure, 'text');
            app.PosZEditFieldLabel = uilabel(app.UIFigure);
            app.PosZEditFieldLabel.Text = 'Pos Z';
            app.PosZEditField_direct = uieditfield(app.UIFigure, 'text');
            app.PosYEditField_2Label = uilabel(app.UIFigure);
            app.PosYEditField_2Label.Text = 'Pos Y';
            app.PosYEditField_direct = uieditfield(app.UIFigure, 'text');
            app.PosZEditField_2Label = uilabel(app.UIFigure);
            app.PosZEditField_2Label.Text = 'Pos Z';
            app.PosZEditField_inverse = uieditfield(app.UIFigure, 'numeric');
            app.Joint3angleSliderLabel = uilabel(app.UIFigure);
            app.Joint3angleSliderLabel.Text = 'Joint 3 angle ';
            app.Joint3angleSlider = uislider(app.UIFigure);
            app.Joint3angleSlider.Limits = [0 90];
            app.Joint3angleSlider.ValueChangedFcn = createCallbackFcn(app, @Joint3angleSliderValueChanged, true);
            app.Joint2angleSliderLabel = uilabel(app.UIFigure);
            app.Joint2angleSliderLabel.Text = 'Joint 2 angle';
            app.Joint2angleSlider = uislider(app.UIFigure);
            app.Joint2angleSlider.Limits = [0 90];
            app.Joint2angleSlider.ValueChangedFcn = createCallbackFcn(app, @Joint2angleSliderValueChanged, true);
            app.RobotcontrolcenterLabel = uilabel(app.UIFigure);
            app.RobotcontrolcenterLabel.Text = 'Robot control center';
            app.DirectkinematicsLabel = uilabel(app.UIFigure);
            app.DirectkinematicsLabel.Text = 'Direct kinematics';
            app.InversekinematicsLabel = uilabel(app.UIFigure);
            app.InversekinematicsLabel.Text = 'Inverse kinematics';
            app.Jointangle1EditFieldLabel = uilabel(app.UIFigure);
            app.Jointangle1EditFieldLabel.Text = 'Joint angle 1';
            app.Jointangle1EditField = uieditfield(app.UIFigure, 'text');
            app.Jointangle3EditFieldLabel = uilabel(app.UIFigure);
            app.Jointangle3EditFieldLabel.Text = 'Joint angle 3';
            app.Jointangle3EditField = uieditfield(app.UIFigure, 'text');
            app.Jointangle2EditFieldLabel = uilabel(app.UIFigure);
            app.Jointangle2EditFieldLabel.Text = 'Joint angle 2';
            app.Jointangle2EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_control_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end